classdef AUV_FEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel_2               matlab.ui.container.Panel
        PanelConsole          matlab.ui.container.Panel
        ConsoleLog            matlab.ui.control.TextArea
        PanelMesh             matlab.ui.container.Panel
        ScaleEditField        matlab.ui.control.NumericEditField
        ScaleEditFieldLabel   matlab.ui.control.Label
        ViewGeometryButton    matlab.ui.control.Button
        ImportGeometryButton  matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        PanelRAO              matlab.ui.container.Panel
    end

    
    properties (Access = private)
        TiledLayoutRAO % a tiled layout for plotting RAO
        GeometryPath % a path to the floating geometry
        Model % model of floating geometry 
        GeometryScale % value that scales geometry size
    end
    
    methods (Access = private)
        
        function [] = initPanelRAO(app)
            app.TiledLayoutRAO = tiledlayout(app.PanelRAO,3,1);
            app.TiledLayoutRAO.TileSpacing = 'compact';
            xlabel(app.TiledLayoutRAO,'Period')
            ylabel(app.TiledLayoutRAO,'RAO')

            ax1 = nexttile(app.TiledLayoutRAO,1);
            title(ax1,'Heave')
            hold(ax1,'on')
            ax2 = nexttile(app.TiledLayoutRAO,2);
            title(ax2,'Surge')
            hold(ax2,'on')
            ax3 = nexttile(app.TiledLayoutRAO,3);
            title(ax3,'Sway')
            hold(ax3,'on')

            linkaxes([ax1,ax2,ax3],'xy')
        end
        
        function [] = log(app,text)
            app.ConsoleLog.Value = cellstr([app.ConsoleLog.Value(:)',text]);
            uiprogressdlg(app.UIFigure)
            scroll(app.ConsoleLog, 'bottom');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            initPanelRAO(app);
            app.ConsoleLog.Value = "Welcome to AUV_FEA_Solver 1.0";
            uiprogressdlg(app.UIFigure)
        end

        % Button pushed function: ImportGeometryButton
        function ImportGeometryButtonPushed(app, event)
            [file,path] = uigetfile('*.stl');
            app.GeometryPath = append(path,file);
            figure(app.UIFigure) % requests focus back
            try
                app.Model = createpde();
                importGeometry(app.Model,app.GeometryPath)
                log(app,append("Imported: ",file));
            catch error                
                log(app,error.message);
            end

        end

        % Button pushed function: ViewGeometryButton
        function ViewGeometryButtonPushed(app, event)
            figure(1)
            try
                pdegplot(app.Model,"FaceLabels","on");
            catch error                
                log(app,error.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelRAO = uipanel(app.UIFigure);
            app.PanelMesh = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.PanelMesh);
            title(app.UIAxes, 'Floating Geometry')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-1 0.5];
            app.UIAxes.YLim = [-0.5 0.5];
            app.UIAxes.ZLim = [-0.5 0];
            app.UIAxes.XTick = [-1 -0.5 0 0.5];
            app.UIAxes.XTickLabel = {'-1'; '-0.5'; '0'; '0.5'};
            app.UIAxes.YTick = [-0.5 0 0.5];
            app.UIAxes.YTickLabel = {'-0.5'; '0'; '0.5'};
            app.UIAxes.ZTick = [-0.5 0];
            app.UIAxes.ZTickLabel = {'-0.5'; '0'};
            app.ImportGeometryButton = uibutton(app.PanelMesh, 'push');
            app.ImportGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @ImportGeometryButtonPushed, true);
            app.ImportGeometryButton.Text = 'Import Geometry';
            app.ViewGeometryButton = uibutton(app.PanelMesh, 'push');
            app.ViewGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @ViewGeometryButtonPushed, true);
            app.ViewGeometryButton.Text = 'View Geometry';
            app.ScaleEditFieldLabel = uilabel(app.PanelMesh);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.PanelMesh, 'numeric');
            app.ScaleEditField.Value = 1;
            app.PanelConsole = uipanel(app.UIFigure);
            app.ConsoleLog = uitextarea(app.PanelConsole);
            app.ConsoleLog.Editable = 'off';
            app.ConsoleLog.Enable = 'off';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AUV_FEA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end